classdef Rakieta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        STARTButton                   matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        WybierzsilnikButtonGroup      matlab.ui.container.ButtonGroup
        Merlin1DButton                matlab.ui.control.RadioButton
        RaptorButton                  matlab.ui.control.RadioButton
        F1Button                      matlab.ui.control.RadioButton
        KtNachyleniaGaugeLabel        matlab.ui.control.Label
        KtNachyleniaGauge             matlab.ui.control.Gauge
        CigusilnikwSliderLabel        matlab.ui.control.Label
        CigusilnikwSlider             matlab.ui.control.Slider
        Paliwo2GaugeLabel             matlab.ui.control.Label
        Paliwo2Gauge                  matlab.ui.control.LinearGauge
        Paliwo1Gauge_2Label           matlab.ui.control.Label
        Paliwo1Gauge_2                matlab.ui.control.LinearGauge
        Odczenie1EtapuSwitchLabel     matlab.ui.control.Label
        Odczenie1EtapuSwitch          matlab.ui.control.ToggleSwitch
        MasaadunkuipaliwaPanel        matlab.ui.container.Panel
        Masa1EtapukgEditFieldLabel    matlab.ui.control.Label
        Masa1EtapukgEditField         matlab.ui.control.NumericEditField
        Masa2EtapukgEditFieldLabel    matlab.ui.control.Label
        Masa2EtapukgEditField         matlab.ui.control.NumericEditField
        Masa1PaliwakgEditFieldLabel   matlab.ui.control.Label
        Masa1PaliwakgEditField        matlab.ui.control.NumericEditField
        Masa2PaliwakgEditFieldLabel   matlab.ui.control.Label
        Masa2PaliwakgEditField        matlab.ui.control.NumericEditField
        PocztkowenachylenieKnobLabel  matlab.ui.control.Label
        PocztkowenachylenieKnob       matlab.ui.control.Knob
        Lamp_2                        matlab.ui.control.Lamp
        STOPButton                    matlab.ui.control.Button
        MasaPanel                     matlab.ui.container.Panel
        kgLabel                       matlab.ui.control.Label
        kgLabel_2                     matlab.ui.control.Label
        kgLabel_3                     matlab.ui.control.Label
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes5                       matlab.ui.control.UIAxes
        UIAxes6                       matlab.ui.control.UIAxes
        UIAxes7                       matlab.ui.control.UIAxes
        UIAxes8                       matlab.ui.control.UIAxes
        UIAxes9                       matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
    end

    
    properties (Access = private)
        %Zmienne Rakiety
        Masa_Paliwa_E1 = 5000;                  % Masa paliwa w etapie 1 (kg)
        Masa_Paliwa_E2 = 2500;                   % Masa paliwa w etapie 2 (kg)
        Masa_E1 = 560;                          % Masa pierwszego etapu (kg)
        Masa_E2 = 1000;                         % Masa drugiego etapu (kg)
        Kat0 = 89;
        
        %Parametry Silnika 1
        n1 = 1;                                   % Procent ustawionego ciagu silnika
        dm1 = 321;                               % Szybkosc spalania paliwa (kg/s)
        P1 = 90000;                            % Cisnienie w dyszy (Pa)
        v1 = 3050;                              % Predkosc wylotu spalin z dyszy (m/s)
        A1 = 0.66;                            % Powierzchnia dyszy (m^2)
        Masa_Silnika1 = 470;
        
        %Parametry Silnika 2
        n2 = 1;                                   % Procent ustawionego ciagu silnika
        dm2 = 321;                               % Szybkosc spalania paliwa (kg/s)
        P2 = 80000;                            % Cisnienie w dyszy (Pa)
        v2 = 3050;                              % Predkosc wylotu spalin z dyszy (m/s)
        A2 = 7.55;                            % Powierzchnia dyszy (m^2)
        Masa_Silnika2 = 470;
        
        rozlaczenie = 0;
        stop = 0;
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startup(app)
            clear
            clc
        end

        % Value changed function: Masa1EtapukgEditField
        function Masa1Etapu(app, event)
            value = app.Masa1EtapukgEditField.Value;
            app.Masa_E1 = value;
        end

        % Value changed function: Masa2EtapukgEditField
        function Masa2Etapu(app, event)
            value = app.Masa2EtapukgEditField.Value;
            app.Masa_E2 = value;
        end

        % Value changed function: Masa1PaliwakgEditField
        function Masa1Paliwa(app, event)
            value = app.Masa1PaliwakgEditField.Value;
            app.Masa_Paliwa_E1 = value;
        end

        % Value changed function: Masa2PaliwakgEditField
        function Masa2Paliwa(app, event)
            value = app.Masa2PaliwakgEditField.Value;
            app.Masa_Paliwa_E2 = value;
        end

        % Selection changed function: WybierzsilnikButtonGroup
        function Wybierzsilnik(app, event)
            selectedButton = app.WybierzsilnikButtonGroup.SelectedObject;
            disp(selectedButton);
            switch app.WybierzsilnikButtonGroup.SelectedObject.Text
                case 'Merlin 1D'
                app.dm1 = 321;                               
                app.P1 = 90000;                            
                app.v1 = 3050;                            
                app.A1 = 0.66; 
                app.Masa_Silnika1 = 470;
                app.dm2 = 321;                               
                app.P2 = 80000;                            
                app.v2 = 3050;                             
                app.A2 = 7.55;
                app.Masa_Silnika2 = 470;
                case 'F-1'
                app.dm1 = 2578;                               
                app.P1 = 90000;                            
                app.v1 = 2600;                            
                app.A1 = 10.75; 
                app.Masa_Silnika1 = 8391;
                app.dm2 = 2578;                               
                app.P2 = 90000;                            
                app.v2 = 2600;                             
                app.A2 = 10.75;
                app.Masa_Silnika2 = 8391;
                case 'Raptor'
                app.dm1 = 565;                               
                app.P1 = 90000;                            
                app.v1 = 3400;                            
                app.A1 = 7.55; 
                app.Masa_Silnika1 = 1500;
                app.dm2 = 565;                               
                app.P2 = 90000;                            
                app.v2 = 3700;                             
                app.A2 = 8.55;
                app.Masa_Silnika2 = 1500;
            end
        end

        % Button pushed function: STARTButton
        function START(app, event)
            
            app.Lamp.Color = [1 0 0];
            app.WybierzsilnikButtonGroup.Enable = 'off';
            app.PocztkowenachylenieKnob.Enable = 'off';
            
%Parametry Rakiety
    tz = 300;                                                     % Czas odłączenia etapów
    C = 0.75;                                                     % Wspolczynnik oporu aerodynamicznego
    Ar = 3;                                                       % Powierzchnia rakiety (m^2)
    Masa_R = app.Masa_Paliwa_E1 + app.Masa_E1 + app.Masa_Paliwa_E2 + app.Masa_E2 + app.Masa_Silnika1 + app.Masa_Silnika2;            
    Paliwo1 = app.Masa_Paliwa_E1; 
    Paliwo2 = app.Masa_Paliwa_E2; 

% Parametery symulacji
    Delta = 0.01;                                                 % Krok czasowy 
    Pamiec = 300000;                                              % Ilosc krokow symulacji

% Alokacja macierzy
    t = zeros(1, Pamiec);
    Ciag = zeros(1, Pamiec);
    Masa = zeros(1, Pamiec);
    Kat = zeros(1, Pamiec);
    Opor = zeros(1, Pamiec);
    Fx = zeros(1, Pamiec);
    Fy = zeros(1, Pamiec);
    Ax = zeros(1, Pamiec);
    Ay = zeros(1, Pamiec);
    Vx = zeros(1, Pamiec);
    Vy = zeros(1, Pamiec);
    x = zeros(1, Pamiec);
    y = zeros(1, Pamiec);
    Cisnienie = zeros(1, Pamiec);
    Grawitacja = zeros(1, Pamiec);
    Gestosc = zeros(1, Pamiec);

%Stałe
    g = 9.806;                              % Grawitacja (m/s^2)
    r = 1.21;                               % Gestosc powietrza (kg/m^3) w temp. 20 stopni Celcujusza
    Pa = 101325;                            % Cisnienie atmosferyczne (Pa)
    Rz = 6.37*10^6;                         % Promien Ziemii

%Początkowe wartości
    Cisnienie(1) = Pa;              % Poczatkowe ciśnienie
    Kat(1) = app.Kat0;              % Poczatkowy kat nachylenia (deg)
    Vx(1) = 0;                      % Poczatkowa predkosc w płaszczyźnie X (m/s)
    Vy(1) = 0;                      % Początkowa predkosc w płaszczyźnie Y (m/s)
    x(1) = 0;                       % Początkowa pozycja w pł. X (m)
    y(1) = 0.1;                     % Początkowa pozycja w pł. Y (m)
    Masa(1) = Masa_R;               % Początkowa masa rakiety (kg)
    faza_lotu = 1;
    Grawitacja(1) = g;              % Grawitacja w punkcie startu
    Gestosc(1) = r;                 % Gestosc powietrza w punkcie startu

    n = 1;                          % Początkowy krok
    k = 1;                          % Zmienna pomocniczna 1
    j = 1;                          % Znienna pomocniczna 2
    opoznienie = 0;                 % Opoznienie przy odlaczaniu etapu 1.

while (y(n) > 0  && faza_lotu ~= 0)    
    
    n = n+1;                      
    t(n)= (n-1)*Delta;                             
    
    if (Paliwo1 <= 5 && faza_lotu == 1) || (app.rozlaczenie == 1 && faza_lotu == 1)
        faza_lotu = 2;
        app.rozlaczenie = 0;
        app.Odczenie1EtapuSwitch.Value = 1;
        app.Lamp_2.Color = [1 0 0];
    end
    
    if faza_lotu == 2 && opoznienie == 0
        Masa(n) = Masa(n-1) - app.Masa_E1 - app.Masa_Silnika1 - Paliwo1;
        faza_lotu = 3;
        opoznienie=1;
    end
    
    if (Paliwo2 <=5 && faza_lotu == 3)
        faza_lotu = 4;
    end
    
    if faza_lotu == 1
        Masa(n) = Masa(n-1)-app.n1*app.dm1*Delta;
        Paliwo1 = Paliwo1 - app.n1*app.dm1*Delta;
        Ciag(n) = app.n1*(app.v1*app.dm1+app.A1*(app.P1-Cisnienie(n-1)));
    end
    
    if (faza_lotu == 3 && opoznienie > tz)
        Masa(n) = Masa(n-1)-app.n2*app.dm2*Delta;
        Paliwo2 = Paliwo2 - app.n2*app.dm2*Delta;
        Ciag(n) = app.n2*(app.v2*app.dm2+app.A2*(app.P2-Cisnienie(n-1)));
    end
    
    if faza_lotu == 4
        Masa(n) = Masa(n-1); 
        Ciag(n) = 0;
    end
        
    % Obliczenia grawitacji
    Grawitacja(n) = Grawitacja(1)*(Rz/(Rz+y(n-1)))^2;
    
    % Obliczenia ciśnienia (Równanie barometryczne)
    Cisnienie(n) = Cisnienie(1)*exp(-1.165363135*10^(-4)*y(n-1));
    
    % Obliczenia gęstości powietrza
    %Gestosc(n) = Cisnienie(n)/(287.05*293.15);
    Gestosc(n) = Gestosc(1)*exp(-1.165363135*10^(-4)*y(n-1));
  
    % Obliczenia siły oporu
    Opor(n)= 0.5*C*Gestosc(n)*Ar*(Vx(n-1)^2+Vy(n-1)^2);                       

    % Obliczenia sił w płaszczyznach X i Y 
    Fx(n)= Ciag(n)*cosd(Kat(n-1))-Opor(n)*cosd(Kat(n-1));                           
    Fy(n)= Ciag(n)*sind(Kat(n-1))-(Masa(n)*Grawitacja(n))-Opor(n)*sind(Kat(n-1));    
        
    % Obliczenia przyspieszenia
    Ax(n)= Fx(n)/Masa(n);                      
    Ay(n)= Fy(n)/Masa(n);                       
	
    % Obliczenia prędości
    Vx(n)=Vx(n-1) + Ax(n)*Delta;                
    Vy(n)=Vy(n-1) + Ay(n)*Delta;               
	
    % Obliczenia trajektorii
    x(n)= x(n-1)+Vx(n)*Delta;                 
    y(n)= y(n-1)+Vy(n)*Delta;                   
    
    % Obliczenia kąta nachylenia
    Kat(n)= atand(Vy(n)/Vx(n));      
    
    if faza_lotu == 3
        opoznienie=opoznienie+1;
        Masa(n+1)=Masa(n);
    end

    if y(n)>350000 || app.stop == 1
        faza_lotu = 0;
    end
    
    if n == 50*k
        plot(app.UIAxes,x(1:n),y(1:n));
        plot(app.UIAxes3,t(1:n),Vy(1:n));
        plot(app.UIAxes7,t(1:n),Vx(1:n));
        plot(app.UIAxes2,t(1:n),Ciag(1:n));
        app.KtNachyleniaGauge.Value = Kat(n);
        app.Paliwo1Gauge_2.Value = round(Paliwo1/app.Masa_Paliwa_E1*100);
        app.Paliwo2Gauge.Value = round(Paliwo2/app.Masa_Paliwa_E2*100);
        drawnow;
        k = k + 1;
    end
    
    if n == 100*j
        plot(app.UIAxes4,t(1:n),Masa(1:n));
        plot(app.UIAxes5,t(1:n),Opor(1:n));
        plot(app.UIAxes6,t(1:n),Grawitacja(1:n));
        plot(app.UIAxes8,t(1:n),Cisnienie(1:n));
        plot(app.UIAxes9,t(1:n),(sqrt(Ax(1:n).^2+Ay(1:n).^2)))
        drawnow;
        j = j + 1;
    end
end
        end

        % Value changed function: CigusilnikwSlider
        function CigusilnikwSliderValue(app, event)
            value = app.CigusilnikwSlider.Value;
            app.n1 = value/100;
            app.n2 = value/100;
        end

        % Value changed function: Odczenie1EtapuSwitch
        function Odczenie1EtapuSwitchValue(app, event)
            value = app.Odczenie1EtapuSwitch.Value;
            app.rozlaczenie = value;
            app.Lamp_2.Color = [1 0 0];
        end

        % Value changed function: PocztkowenachylenieKnob
        function PocztkowenachylenieKnobValue(app, event)
            value = app.PocztkowenachylenieKnob.Value;
            app.Kat0 = value;
            app.KtNachyleniaGauge.Value = value;
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            app.stop = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @START, true);
            app.STARTButton.Text = 'START';
            app.Lamp = uilamp(app.UIFigure);
            app.WybierzsilnikButtonGroup = uibuttongroup(app.UIFigure);
            app.WybierzsilnikButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Wybierzsilnik, true);
            app.WybierzsilnikButtonGroup.Title = 'Wybierz silnik';
            app.Merlin1DButton = uiradiobutton(app.WybierzsilnikButtonGroup);
            app.Merlin1DButton.Tag = '1D';
            app.Merlin1DButton.Text = 'Merlin 1D';
            app.Merlin1DButton.Value = true;
            app.RaptorButton = uiradiobutton(app.WybierzsilnikButtonGroup);
            app.RaptorButton.Tag = 'Raptor';
            app.RaptorButton.Text = 'Raptor';
            app.F1Button = uiradiobutton(app.WybierzsilnikButtonGroup);
            app.F1Button.Tag = 'F1';
            app.F1Button.Text = 'F-1';
            app.KtNachyleniaGaugeLabel = uilabel(app.UIFigure);
            app.KtNachyleniaGaugeLabel.Text = 'Kąt Nachylenia';
            app.KtNachyleniaGauge = uigauge(app.UIFigure, 'circular');
            app.KtNachyleniaGauge.Limits = [-90 90];
            app.KtNachyleniaGauge.Value = 89;
            app.CigusilnikwSliderLabel = uilabel(app.UIFigure);
            app.CigusilnikwSliderLabel.Text = '% Ciągu silników';
            app.CigusilnikwSlider = uislider(app.UIFigure);
            app.CigusilnikwSlider.ValueChangedFcn = createCallbackFcn(app, @CigusilnikwSliderValue, true);
            app.CigusilnikwSlider.Value = 100;
            app.Paliwo2GaugeLabel = uilabel(app.UIFigure);
            app.Paliwo2GaugeLabel.Text = 'Paliwo 2';
            app.Paliwo2Gauge = uigauge(app.UIFigure, 'linear');
            app.Paliwo2Gauge.Value = 100;
            app.Paliwo1Gauge_2Label = uilabel(app.UIFigure);
            app.Paliwo1Gauge_2Label.Text = 'Paliwo 1';
            app.Paliwo1Gauge_2 = uigauge(app.UIFigure, 'linear');
            app.Paliwo1Gauge_2.Value = 100;
            app.Odczenie1EtapuSwitchLabel = uilabel(app.UIFigure);
            app.Odczenie1EtapuSwitchLabel.Text = 'Odłączenie 1. Etapu';
            app.Odczenie1EtapuSwitch = uiswitch(app.UIFigure, 'toggle');
            app.Odczenie1EtapuSwitch.Items = {'', 'Odłącz'};
            app.Odczenie1EtapuSwitch.ItemsData = [0 1];
            app.Odczenie1EtapuSwitch.ValueChangedFcn = createCallbackFcn(app, @Odczenie1EtapuSwitchValue, true);
            app.Odczenie1EtapuSwitch.Value = 0;
            app.MasaadunkuipaliwaPanel = uipanel(app.UIFigure);
            app.MasaadunkuipaliwaPanel.Title = 'Masa ładunku i paliwa';
            app.Masa1EtapukgEditFieldLabel = uilabel(app.MasaadunkuipaliwaPanel);
            app.Masa1EtapukgEditFieldLabel.Text = 'Masa 1. Etapu (kg)';
            app.Masa1EtapukgEditField = uieditfield(app.MasaadunkuipaliwaPanel, 'numeric');
            app.Masa1EtapukgEditField.ValueChangedFcn = createCallbackFcn(app, @Masa1Etapu, true);
            app.Masa1EtapukgEditField.Value = 560;
            app.Masa2EtapukgEditFieldLabel = uilabel(app.MasaadunkuipaliwaPanel);
            app.Masa2EtapukgEditFieldLabel.Text = 'Masa 2. Etapu (kg)';
            app.Masa2EtapukgEditField = uieditfield(app.MasaadunkuipaliwaPanel, 'numeric');
            app.Masa2EtapukgEditField.ValueChangedFcn = createCallbackFcn(app, @Masa2Etapu, true);
            app.Masa2EtapukgEditField.Value = 1000;
            app.Masa1PaliwakgEditFieldLabel = uilabel(app.MasaadunkuipaliwaPanel);
            app.Masa1PaliwakgEditFieldLabel.Text = 'Masa 1. Paliwa (kg)';
            app.Masa1PaliwakgEditField = uieditfield(app.MasaadunkuipaliwaPanel, 'numeric');
            app.Masa1PaliwakgEditField.ValueChangedFcn = createCallbackFcn(app, @Masa1Paliwa, true);
            app.Masa1PaliwakgEditField.Value = 5000;
            app.Masa2PaliwakgEditFieldLabel = uilabel(app.MasaadunkuipaliwaPanel);
            app.Masa2PaliwakgEditFieldLabel.Text = 'Masa 2. Paliwa (kg)';
            app.Masa2PaliwakgEditField = uieditfield(app.MasaadunkuipaliwaPanel, 'numeric');
            app.Masa2PaliwakgEditField.ValueChangedFcn = createCallbackFcn(app, @Masa2Paliwa, true);
            app.Masa2PaliwakgEditField.Value = 2500;
            app.PocztkowenachylenieKnobLabel = uilabel(app.UIFigure);
            app.PocztkowenachylenieKnobLabel.Text = 'Początkowe nachylenie';
            app.PocztkowenachylenieKnob = uiknob(app.UIFigure, 'continuous');
            app.PocztkowenachylenieKnob.Limits = [5 90];
            app.PocztkowenachylenieKnob.ValueChangedFcn = createCallbackFcn(app, @PocztkowenachylenieKnobValue, true);
            app.PocztkowenachylenieKnob.Value = 89;
            app.Lamp_2 = uilamp(app.UIFigure);
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.MasaPanel = uipanel(app.UIFigure);
            app.MasaPanel.Title = 'Masa';
            app.kgLabel = uilabel(app.MasaPanel);
            app.kgLabel.Text = '470 kg';
            app.kgLabel_2 = uilabel(app.MasaPanel);
            app.kgLabel_2.Text = '1500 kg';
            app.kgLabel_3 = uilabel(app.MasaPanel);
            app.kgLabel_3.Text = '8391 kg';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Prędkość Vy')
            xlabel(app.UIAxes3, 'Czas (s)')
            ylabel(app.UIAxes3, 'Prędkość (m/s)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Ciąg')
            xlabel(app.UIAxes2, 'Czas (s)')
            ylabel(app.UIAxes2, 'Siła (N)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Masa')
            xlabel(app.UIAxes4, 'Czas (s)')
            ylabel(app.UIAxes4, 'Masa (kg)')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Trajektoria')
            xlabel(app.UIAxes, 'X (m)')
            ylabel(app.UIAxes, 'Y (m)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Opór')
            xlabel(app.UIAxes5, 'Czas (s)')
            ylabel(app.UIAxes5, 'Siła (N)')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Siła Grawitacji')
            xlabel(app.UIAxes6, 'Czas (s)')
            ylabel(app.UIAxes6, 'Siła (N)')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.UIFigure);
            title(app.UIAxes7, 'Prędkość Vx')
            xlabel(app.UIAxes7, 'Czas (s)')
            ylabel(app.UIAxes7, 'Prędkość (m/s)')
            zlabel(app.UIAxes7, 'Z')
            app.UIAxes8 = uiaxes(app.UIFigure);
            title(app.UIAxes8, 'Ciśnienie')
            xlabel(app.UIAxes8, 'Czas (s)')
            ylabel(app.UIAxes8, 'Ciśnienie (Pa)')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.UIFigure);
            title(app.UIAxes9, 'Przyspieszenie')
            xlabel(app.UIAxes9, 'Czas (s)')
            ylabel(app.UIAxes9, 'Przyspieszenie (m/s^2)')
            zlabel(app.UIAxes9, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.WybierzsilnikButtonGroup.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Rakieta

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startup)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end